classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImportButton              matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        FileNameEditField         matlab.ui.control.EditField
        FileNameEditFieldLabel    matlab.ui.control.Label
        NewPointChartButton       matlab.ui.control.Button
        DeletePointChartButton    matlab.ui.control.Button
        Switch                    matlab.ui.control.Switch
        Label                     matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        MouseCommandsButtonGroup  matlab.ui.container.ButtonGroup
        InsertPointButton         matlab.ui.control.RadioButton
        AppendPointButton         matlab.ui.control.RadioButton
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Points = []; % Description
    end
    
    methods (Access = private)
        
        function app = Plot_Catmull_Rom_Spline_Segment(app, P1, P2, P3, P4, Sample_Num, r)
            T = 0.5;
            x = zeros(Sample_Num, 1);
            y = zeros(Sample_Num, 1);
            s1x = zeros(Sample_Num, 1);
            s1y = zeros(Sample_Num, 1);
            s2x = zeros(Sample_Num, 1);
            s2y = zeros(Sample_Num, 1);
            for i = 1:Sample_Num
                u  = (i-1) / (Sample_Num-1);

                a = u^3 - 2*u^2 + u;
                da = 3*u^2 - 4*u + 1;

                b = u^2 - u^3;
                db = 2*u - 3*u^2;

                c = 3*u^2 - 2*u^3;
                dc = 6*u - 6*u^2;

                x(i) = T*( a*( P3(1) - P1(1) )+b*( P2(1) - P4(1) ) ) + P2(1) + c*( P3(1) - P2(1) );
                y(i) = T*( a*( P3(2)-P1(2) )+b*( P2(2)-P4(2) ) ) + P2(2) + c*( P3(2) - P2(2) );

                dx = T*( da*( P3(1) - P1(1) )+db*( P2(1) - P4(1) ) ) + dc*( P3(1) - P2(1) );
                dy = T*( da*( P3(2)-P1(2) )+db*( P2(2)-P4(2) ) ) + dc*( P3(2) - P2(2));

                s1x(i) = x(i) + r*cos(atan2(dy, dx)-(pi/2));
                s1y(i) = y(i) + r*sin(atan2(dy, dx)-(pi/2));

                s2x(i) = x(i) + r*cos(atan2(dy, dx)+(pi/2));
                s2y(i) = y(i) + r*sin(atan2(dy, dx)+(pi/2));
                disp(atan(dx));
            end
            
            plot(app.UIAxes, x, y, 'r', 'LineWidth', 5);
            plot(app.UIAxes, s1x, s1y, 'k');
            plot(app.UIAxes, s2x, s2y, 'k');
        end
        function app = Plot_Full_Spline(app)
            Sample_Num = 50;
            r = 0.6;
            [app, First_Control] = app.Make_Control_Point(app.Points(1,:), app.Points(2,:));
            [app, Last_Control] = app.Make_Control_Point(app.Points(end,:), app.Points(end-1,:));
            Knots = [First_Control;app.Points;Last_Control];
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            app.ScatterPoses();
            for i = 1:(size(Knots,1)-3)
                app.Plot_Catmull_Rom_Spline_Segment(Knots(i,:), Knots(i+1,:), Knots(i+2,:), Knots(i+3,:), Sample_Num, r);
            end
            [~, x, y] = app.End_Semi_Circle(Knots(1,:), Knots(2,:), r, Sample_Num);
            plot(app.UIAxes, x, y, 'k');
            [~, x, y] = app.End_Semi_Circle(Knots(end,:), Knots(end-1,:), r, Sample_Num);
            plot(app.UIAxes, x, y, 'k');

        end
        function [app, x, y] = End_Semi_Circle(app, End, Before, R, sample_num)
            Diff = End-Before;
            theta = atan2(Diff(2), Diff(1));
            x = Before(1) + R*cos(linspace(theta-(pi/2), theta+(pi/2),sample_num));
            y = Before(2) + R*sin(linspace(theta-(pi/2), theta+(pi/2),sample_num));
        end
        function [app, C_Point] = Make_Control_Point(app, Last_Point, Point_Before_Last)
            C_Point = 2*Last_Point - Point_Before_Last;
        end
        function app = AppendPoint(app, x, y)
            app.Points = [app.Points; x, y];
        end
        function app = InsertPoint(app, New_Row_Index, x, y)
            app.Points = [app.Points(1:New_Row_Index-1,:); x, y; app.Points(New_Row_Index:end, :)];
        end
        function app = DeletePoint(app, index)
            if size(app.Points,1) == 0
                return;
            elseif size(app.Points,1) == 1
                app.Points = [];
            else
                app.Points = [app.Points(1:index-1,:); app.Points(index+1:end, :)];
            end
        end
        function app = UpdateUI(app)
            app.UITable.Data = horzcat((1:size(app.Points,1))',app.Points,zeros(size(app.Points,1),1));
            if size(app.Points, 1) > 1
                app.Plot_Full_Spline();
            else
                cla(app.UIAxes);
                app.ScatterPoses();
            end
        end
        
        function app = ScatterPoses(app)
            for i = 1:size(app.Points,1)
                scatter(app.UIAxes, app.Points(i,1), app.Points(i,2));
                text(app.UIAxes, app.Points(i,1)+0.01, app.Points(i,2)+0.01, string(i),  "Color",'#FF00FF');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Get X and Y of mouse
            fhv = app.UIFigure.HandleVisibility;
            app.UIFigure.HandleVisibility = 'callback';
            set(0, 'CurrentFigure', app.UIFigure);
            [x,y,~] = ginput(1);
            app.UIFigure.HandleVisibility = fhv;    

            % Check if mouse click is within graph bounds
            xAxisLimits = app.UIAxes.XAxis.get.Limits;
            yAxisLimits = app.UIAxes.YAxis.get.Limits;
            Is_Point_Within = ...
                x >= xAxisLimits(1) &&...
                x <= xAxisLimits(2) &&...
                y >= yAxisLimits(1) &&...
                y <= yAxisLimits(2);
            if ~Is_Point_Within
                return;
            end
            
            if app.AppendPointButton.Value
                app.AppendPoint(x,y);
                app.UpdateUI();
            elseif app.InsertPointButton.Value
                if isempty(app.UITable.Selection)
                    Row_Num = 1;
                else
                    Row_Num = app.UITable.Selection;
                end
                app.InsertPoint(Row_Num, x, y);
                app.UpdateUI();
            end
        end

        % Button pushed function: NewPointChartButton
        function NewPointChartButtonPushed(app, event)
            if isempty(app.UITable.Selection)
                app.AppendPoint(0,0);
            else
                Row_Num = app.UITable.Selection;
                if Row_Num == 1
                    x = 0;
                    y = 0;
                else
                    x = ( app.Points(Row_Num-1,1) + app.Points(Row_Num,1) ) / 2;
                    y = ( app.Points(Row_Num-1,2) + app.Points(Row_Num,2) ) / 2;
                end
                app.InsertPoint(Row_Num, x, y);
            end
            app.UpdateUI();
        end

        % Button pushed function: DeletePointChartButton
        function DeletePointChartButtonPushed(app, event)
            if isempty(app.UITable.Selection)
                return;
            end
            app.DeletePoint(app.UITable.Selection);
            app.UpdateUI();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if(event.Indices(2) == 2)
                Limits = app.UIAxes.XAxis.get.Limits;
            elseif(event.Indices(2) == 3)
                Limits = app.UIAxes.YAxis.get.Limits;
            end
            if event.NewData < Limits(1) || event.NewData > Limits(2)
                app.UpdateUI();
                msgbox('value out of bounds');
                return;
            end
            app.Points = app.UITable.Data(:,2:3);
            app.UpdateUI();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'Blue')
                app.UIFigure.Color = '#1F5776';
            elseif strcmp(value, 'Red')
                app.UIFigure.Color = '#C83126';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 15];
            app.UIAxes.YLim = [0 8];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.MouseCommandsButtonGroup = uibuttongroup(app.UIFigure);
            app.MouseCommandsButtonGroup.Title = 'Mouse Commands';
            app.AppendPointButton = uiradiobutton(app.MouseCommandsButtonGroup);
            app.AppendPointButton.Text = 'Append Point';
            app.AppendPointButton.Value = true;
            app.InsertPointButton = uiradiobutton(app.MouseCommandsButtonGroup);
            app.InsertPointButton.Text = 'Insert Point';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'#'; 'X'; 'Y'; 'Rot'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Multiselect = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Red', 'Blue'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Red';
            app.DeletePointChartButton = uibutton(app.UIFigure, 'push');
            app.DeletePointChartButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointChartButtonPushed, true);
            app.DeletePointChartButton.Text = 'Delete Point';
            app.NewPointChartButton = uibutton(app.UIFigure, 'push');
            app.NewPointChartButton.ButtonPushedFcn = createCallbackFcn(app, @NewPointChartButtonPushed, true);
            app.NewPointChartButton.Text = 'New Point';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.Text = 'Import';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end